classdef ModeVisualizer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ModeVisualizerUIFigure   matlab.ui.Figure
        UIAxes                   matlab.ui.control.UIAxes
        FrequenciesListBoxLabel  matlab.ui.control.Label
        FrequenciesListBox       matlab.ui.control.ListBox
        LoadmodesButton          matlab.ui.control.Button
        VisualizemodeButton      matlab.ui.control.Button
        ScaleEditFieldLabel      matlab.ui.control.Label
        ScaleEditField           matlab.ui.control.EditField
        ViewEditFieldLabel       matlab.ui.control.Label
        ViewEditField            matlab.ui.control.EditField
    end

    
    properties (Access = private)
        lam % vector of eigenvalues
        V % matrix of eigenvectors
        ind % indices of frequencies sorted in ascending order
    end
    
    properties (Access = private) % configuration parameters for the model
        X0, ...
        x0_SA, x0_B, RJ_SA, RJ_B, ...
        x01_C, RE1_C, r1_C, ...
        x02_C, RE2_C, r2_C, ...
        R0, knots, N, Nbrev, ...
        maskSADof, maskBDof, ...
        indexSADof, indexBDof
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadmodesButton
        function LoadmodesButtonPushed(app, event)
            [file,path] = uigetfile('*.mat');
            
            load(strcat(path,file)); %#ok<LOAD> 
            [app.V, D] = eig(AA);
            app.lam = diag(D);
            [~, app.ind] = sort(abs(app.lam));
            app.FrequenciesListBox.Items = ...
                compose('%g',abs(app.lam(app.ind))/2/pi);
            % assign ItemsData property, so that the Value property returns
            % index of the chosen frequency, and not the frequency itself
            app.FrequenciesListBox.ItemsData = ...
                compose('%d',(1:length(app.lam)));
            
            app.X0 = X0; %#ok<ADPROPLC> 
            app.x0_SA = x0_SA; %#ok<ADPROPLC> 
            app.x0_B = x0_B; %#ok<ADPROPLC> 
            app.RJ_SA = RJ_SA; %#ok<ADPROPLC> 
            app.RJ_B = RJ_B; %#ok<ADPROPLC> 
            app.x01_C = x01_C; %#ok<ADPROPLC> 
            app.RE1_C = RE1_C; %#ok<ADPROPLC> 
            app.r1_C = r1_C; %#ok<ADPROPLC> 
            app.x02_C = x02_C; %#ok<ADPROPLC> 
            app.RE2_C = RE2_C; %#ok<ADPROPLC> 
            app.r2_C = r2_C; %#ok<ADPROPLC> 
            app.R0 = R0; %#ok<ADPROPLC>
            app.knots = knots; %#ok<ADPROPLC> 
            app.N = N; %#ok<ADPROPLC> 
            app.Nbrev = Nbrev; %#ok<ADPROPLC> 
            app.maskSADof = maskSADof; %#ok<ADPROPLC> 
            app.maskBDof = maskBDof; %#ok<ADPROPLC> 
            app.indexSADof = indexSADof; %#ok<ADPROPLC> 
            app.indexBDof = indexBDof; %#ok<ADPROPLC> 
        end

        % Button pushed function: VisualizemodeButton
        function VisualizemodeButtonPushed(app, event)
            addpath ..
            addpath ../RigidBody/
            
            n = str2num(app.FrequenciesListBox.Value); %#ok<ST2NM> 
            
            thet = pi/2+linspace(0,8*pi,101); % angle to rotate complex 
                                              % eigenvectors
            expit = exp(1i*thet); 

            scale = max(1,str2double(app.ScaleEditField.Value));
            view_ = str2num(app.ViewEditField.Value); %#ok<ST2NM> 
            if (isempty(view_))
                view_ = [1,-1,1];
            end
            modeshape = app.X0 + scale*(2*real(app.V(:,app.ind(n))*expit));
            [~, ~, P, ~] = ...
                Template2StructureDynamicsOutputs(thet, modeshape', [], ...
                                      app.RJ_SA, app.RJ_B, ...
                                      app.x01_C, app.RE1_C, app.r1_C, ...
                                      app.x02_C, app.RE2_C, app.r2_C, ...
                                      app.R0, app.N, app.Nbrev, ...
                                      app.maskSADof, app.maskBDof, ...
                                      app.indexSADof, app.indexBDof);
            f = abs(app.lam(app.ind(n)))/2/pi;
            xi = -real(app.lam(app.ind(n)))/abs(app.lam(app.ind(n)));
            titlestr = sprintf('f = %.3f Hz; xi = %.3f', f, xi);
            QuickAndDirtyAnimation(P(:,1),P-P(:,1),app.x0_SA,app.x0_B,...
                app.knots,1,view_,app.UIAxes,titlestr);
            
            rmpath ..
            rmpath ../RigidBody/
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ModeVisualizerUIFigure = uifigure('Visible', 'off');
            app.ModeVisualizerUIFigure.Name = 'Mode Visualizer';
            app.UIAxes = uiaxes(app.ModeVisualizerUIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.FrequenciesListBoxLabel = uilabel(app.ModeVisualizerUIFigure);
            app.FrequenciesListBoxLabel.Text = 'Frequencies';
            app.FrequenciesListBox = uilistbox(app.ModeVisualizerUIFigure);
            app.LoadmodesButton = uibutton(app.ModeVisualizerUIFigure, 'push');
            app.LoadmodesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadmodesButtonPushed, true);
            app.LoadmodesButton.Text = 'Load modes';
            app.VisualizemodeButton = uibutton(app.ModeVisualizerUIFigure, 'push');
            app.VisualizemodeButton.ButtonPushedFcn = createCallbackFcn(app, @VisualizemodeButtonPushed, true);
            app.VisualizemodeButton.Text = 'Visualize mode';
            app.ScaleEditFieldLabel = uilabel(app.ModeVisualizerUIFigure);
            app.ScaleEditFieldLabel.Text = 'Scale';
            app.ScaleEditField = uieditfield(app.ModeVisualizerUIFigure, 'text');
            app.ViewEditFieldLabel = uilabel(app.ModeVisualizerUIFigure);
            app.ViewEditFieldLabel.Text = 'View';
            app.ViewEditField = uieditfield(app.ModeVisualizerUIFigure, 'text');
            % Show the figure after all components are created
            app.ModeVisualizerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ModeVisualizer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ModeVisualizerUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ModeVisualizerUIFigure)
        end
    end
end